classdef main_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Label                  matlab.ui.control.Label
        VisibilitySlider       matlab.ui.control.Slider
        VisibilitySliderLabel  matlab.ui.control.Label
        ExportAllButton        matlab.ui.control.Button
        SelectFilesButton      matlab.ui.control.Button
        UpdateButton           matlab.ui.control.Button
        ExportCurrentButton    matlab.ui.control.Button
        SeeDetailsButton       matlab.ui.control.Button
        NextButton             matlab.ui.control.Button
        PreviousButton         matlab.ui.control.Button
        ThresholdSlider        matlab.ui.control.Slider
        ThresholdSliderLabel   matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        i % iterate file
        fileList % list of files selected
        threshold
        visibility
        US_data
        FL_data
        scan_depth
        scan_length
        dx
        dy
        x_axis
        y_axis
        depth_corrected
        FL_processed
        FL_image
        depth
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("funcs\")
            addpath("C:\Users\Deniz\Desktop\fl_us_new")
            app.i = 1;
            app.Label.Text = "Select files to start";
            set(findobj(app.UIFigure,'Type','uibutton'), 'Enable','off')
            set(app.SelectFilesButton,'Enable','on')
            app.threshold = 100;
            app.ThresholdSlider.Value = app.threshold;
            app.visibility = 50;
            app.VisibilitySlider.Value = app.visibility;
        end

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            [app.fileList, pathname] = uigetfile("*.h5", "MultiSelect", "on");
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            disp(length(app.fileList))
            addpath(pathname)
            if isa(app.fileList,'cell')
                currentFile = app.fileList(app.i);
            else
                currentFile = app.fileList;
            end
            app.Label.Text = currentFile + " (file " + app.i + " of " + length(app.fileList) + ")";
            set(findobj(app.UIFigure,'Type','uibutton'), 'Enable','on')

            [~, name, format] = fileparts(char(currentFile));
            fname = [name, format];

            app.US_data = H5toUS(fname);
            app.FL_data = H5toFL(fname);
            app.scan_depth = h5readatt(fname, '/', 'depth_mm');
            app.scan_length = h5readatt(fname, '/', 'length_mm'); % scan length in mm
            app.dx = h5readatt(fname, '/', 'dx'); % step size between the A-lines
            app.dy = app.scan_depth / size(app.US_data, 1);

            app.x_axis = app.dx:app.dx:app.scan_length;
            app.y_axis = app.dy:app.dy:app.scan_depth;
            
            [data_rows, data_cols] = size(app.US_data);
            peakPos = zeros(data_rows, data_cols);
            for col = 1:data_cols
                peakFound = false;
                for row = app.threshold + 1:data_rows
                    if app.US_data(row, col) > app.threshold
                        peakPos(row:row + app.visibility, col) = 1;
                        peakFound = true;
                    elseif peakFound
                        break;
                    end
                end
            end
            
            app.depth = zeros(1, size(peakPos, 2));
            for col = 1:size(peakPos, 2)
                row_index = find(peakPos(:, col), 1, 'first');
                if ~isempty(row_index)
                    app.depth(col) = row_index;
                end
            end
            app.depth = (app.depth .* app.scan_depth) ./ size(app.US_data, 1); % add dimensions

            app.FL_processed = richardFL(app.depth);

            app.depth_corrected = sqrt((app.FL_data - app.FL_processed).^2);
            app.depth_corrected = 0.5 - mean(app.depth_corrected) + app.depth_corrected; % shift median to 0.5

            app.FL_image = createFLcmap(app.depth_corrected, "jet");
            app.FL_image = imresize(app.FL_image, [size(app.US_data, 1), size(app.US_data, 2)]);

            imagesc(app.UIAxes, app.x_axis, app.y_axis, app.US_data);
            hold(app.UIAxes, "on");
            FL = imagesc(app.UIAxes, app.x_axis, app.y_axis, app.FL_image);
            colormap(app.UIAxes, "gray")
            selection = app.US_data .* uint8(peakPos);
            set(FL, 'AlphaData', selection);
            axis(app.UIAxes, "tight", "equal")
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            cla(app.UIAxes)
            if app.i < length(app.fileList)
                app.i = app.i + 1;
                currentFile = app.fileList(app.i);
                app.Label.Text = currentFile + " (file " + app.i + " of " + length(app.fileList) + ")";
            else
                app.i = 1;
                currentFile = app.fileList(app.i);
                app.Label.Text = currentFile + " (file " + app.i + " of " + length(app.fileList) + ")";
            end

            if isa(app.fileList,'cell')
                currentFile = app.fileList(app.i);
            else
                currentFile = app.fileList;
            end
            [~, name, format] = fileparts(char(currentFile));
            fname = [name, format];

            app.US_data = H5toUS(fname);
            app.FL_data = H5toFL(fname);
            app.scan_depth = h5readatt(fname, '/', 'depth_mm');
            app.scan_length = h5readatt(fname, '/', 'length_mm'); % scan length in mm
            app.dx = h5readatt(fname, '/', 'dx'); % step size between the A-lines
            app.dy = app.scan_depth / size(app.US_data, 1);

            app.x_axis = app.dx:app.dx:app.scan_length;
            app.y_axis = app.dy:app.dy:app.scan_depth;
            
            [data_rows, data_cols] = size(app.US_data);
            peakPos = zeros(data_rows, data_cols);
            for col = 1:data_cols
                peakFound = false;
                for row = app.threshold + 1:data_rows
                    if app.US_data(row, col) > app.threshold
                        peakPos(row:row + app.visibility, col) = 1;
                        peakFound = true;
                    elseif peakFound
                        break;
                    end
                end
            end
            
            app.depth = zeros(1, size(peakPos, 2));
            for col = 1:size(peakPos, 2)
                row_index = find(peakPos(:, col), 1, 'first');
                if ~isempty(row_index)
                    app.depth(col) = row_index;
                end
            end
            app.depth = (app.depth .* app.scan_depth) ./ size(app.US_data, 1); % add dimensions

            app.FL_processed = richardFL(app.depth);

            app.depth_corrected = sqrt((app.FL_data - app.FL_processed).^2);
            app.depth_corrected = 0.5 - mean(app.depth_corrected) + app.depth_corrected; % shift median to 0.5

            app.FL_image = createFLcmap(app.depth_corrected, "jet");
            app.FL_image = imresize(app.FL_image, [size(app.US_data, 1), size(app.US_data, 2)]);

            imagesc(app.UIAxes, app.x_axis, app.y_axis, app.US_data);
            hold(app.UIAxes, "on");
            FL = imagesc(app.UIAxes, app.x_axis, app.y_axis, app.FL_image);
            colormap(app.UIAxes, "gray")
            selection = app.US_data .* uint8(peakPos);
            set(FL, 'AlphaData', selection);
            axis(app.UIAxes, "tight", "equal")
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            cla(app.UIAxes)
            if app.i > 1
                app.i = app.i - 1;
                currentFile = app.fileList(app.i);
                app.Label.Text = currentFile + " (file " + app.i + " of " + length(app.fileList) + ")";
            else
                app.i = length(app.fileList);
                currentFile = app.fileList(app.i);
                app.Label.Text = currentFile + " (file " + app.i + " of " + length(app.fileList) + ")";
            end

            if isa(app.fileList,'cell')
                currentFile = app.fileList(app.i);
            else
                currentFile = app.fileList;
            end
            [~, name, format] = fileparts(char(currentFile));
            fname = [name, format];

            app.US_data = H5toUS(fname);
            app.FL_data = H5toFL(fname);
            app.scan_depth = h5readatt(fname, '/', 'depth_mm');
            app.scan_length = h5readatt(fname, '/', 'length_mm'); % scan length in mm
            app.dx = h5readatt(fname, '/', 'dx'); % step size between the A-lines
            app.dy = app.scan_depth / size(app.US_data, 1);

            app.x_axis = app.dx:app.dx:app.scan_length;
            app.y_axis = app.dy:app.dy:app.scan_depth;
            
            [data_rows, data_cols] = size(app.US_data);
            peakPos = zeros(data_rows, data_cols);
            for col = 1:data_cols
                peakFound = false;
                for row = app.threshold + 1:data_rows
                    if app.US_data(row, col) > app.threshold
                        peakPos(row:row + app.visibility, col) = 1;
                        peakFound = true;
                    elseif peakFound
                        break;
                    end
                end
            end
            
            app.depth = zeros(1, size(peakPos, 2));
            for col = 1:size(peakPos, 2)
                row_index = find(peakPos(:, col), 1, 'first');
                if ~isempty(row_index)
                    app.depth(col) = row_index;
                end
            end
            app.depth = (app.depth .* app.scan_depth) ./ size(app.US_data, 1); % add dimensions

            app.FL_processed = richardFL(app.depth);

            app.depth_corrected = sqrt((app.FL_data - app.FL_processed).^2);
            app.depth_corrected = 0.5 - mean(app.depth_corrected) + app.depth_corrected; % shift median to 0.5

            app.FL_image = createFLcmap(app.depth_corrected, "jet");
            app.FL_image = imresize(app.FL_image, [size(app.US_data, 1), size(app.US_data, 2)]);

            imagesc(app.UIAxes, app.x_axis, app.y_axis, app.US_data);
            hold(app.UIAxes, "on");
            FL = imagesc(app.UIAxes, app.x_axis, app.y_axis, app.FL_image);
            colormap(app.UIAxes, "gray")
            selection = app.US_data .* uint8(peakPos);
            set(FL, 'AlphaData', selection);
            axis(app.UIAxes, "tight", "equal")
        end

        % Value changing function: ThresholdSlider
        function ThresholdSliderValueChanging(app, event)
            app.threshold = floor(event.Value);
            
            [data_rows, data_cols] = size(app.US_data);
            peakPos = zeros(data_rows, data_cols);
            for col = 1:data_cols
                peakFound = false;
                for row = app.threshold + 1:data_rows
                    if app.US_data(row, col) > app.threshold
                        peakPos(row:row + app.visibility, col) = 1;
                        peakFound = true;
                    elseif peakFound
                        break;
                    end
                end
            end
            
            app.depth = zeros(1, size(peakPos, 2));
            for col = 1:size(peakPos, 2)
                row_index = find(peakPos(:, col), 1, 'first');
                if ~isempty(row_index)
                    app.depth(col) = row_index;
                end
            end
            app.depth = (app.depth .* app.scan_depth) ./ size(app.US_data, 1); % add dimensions

            app.FL_processed = richardFL(app.depth);

            app.depth_corrected = sqrt((app.FL_data - app.FL_processed).^2);
            app.depth_corrected = 0.5 - mean(app.depth_corrected) + app.depth_corrected; % shift median to 0.5

            app.FL_image = createFLcmap(app.depth_corrected, "jet");
            app.FL_image = imresize(app.FL_image, [size(app.US_data, 1), size(app.US_data, 2)]);

            imagesc(app.UIAxes, app.x_axis, app.y_axis, app.US_data);
            hold(app.UIAxes, "on");
            FL = imagesc(app.UIAxes, app.x_axis, app.y_axis, app.FL_image);
            colormap(app.UIAxes, "gray")
            selection = app.US_data .* uint8(peakPos);
            set(FL, 'AlphaData', selection);
            axis(app.UIAxes, "tight", "equal")
        end

        % Value changed function: VisibilitySlider
        function VisibilitySliderValueChanged(app, event)
            app.visibility = floor(app.VisibilitySlider.Value);
            
            [data_rows, data_cols] = size(app.US_data);
            peakPos = zeros(data_rows, data_cols);
            for col = 1:data_cols
                peakFound = false;
                for row = app.threshold + 1:data_rows
                    if app.US_data(row, col) > app.threshold
                        peakPos(row:row + app.visibility, col) = 1;
                        peakFound = true;
                    elseif peakFound
                        break;
                    end
                end
            end
            
            app.depth = zeros(1, size(peakPos, 2));
            for col = 1:size(peakPos, 2)
                row_index = find(peakPos(:, col), 1, 'first');
                if ~isempty(row_index)
                    app.depth(col) = row_index;
                end
            end
            app.depth = (app.depth .* app.scan_depth) ./ size(app.US_data, 1); % add dimensions

            app.FL_processed = richardFL(app.depth);

            app.depth_corrected = sqrt((app.FL_data - app.FL_processed).^2);
            app.depth_corrected = 0.5 - mean(app.depth_corrected) + app.depth_corrected; % shift median to 0.5

            app.FL_image = createFLcmap(app.depth_corrected, "jet");
            app.FL_image = imresize(app.FL_image, [size(app.US_data, 1), size(app.US_data, 2)]);

            imagesc(app.UIAxes, app.x_axis, app.y_axis, app.US_data);
            hold(app.UIAxes, "on");
            FL = imagesc(app.UIAxes, app.x_axis, app.y_axis, app.FL_image);
            colormap(app.UIAxes, "gray")
            selection = app.US_data .* uint8(peakPos);
            set(FL, 'AlphaData', selection);
            axis(app.UIAxes, "tight", "equal")
        end

        % Button pushed function: SeeDetailsButton
        function SeeDetailsButtonPushed(app, event)
            if isa(app.fileList,'cell')
                currentFile = app.fileList(app.i);
            else
                currentFile = app.fileList;
            end
            [~, name, format] = fileparts(char(currentFile));
            fname = [name, format];
            
            figure
            subplot(2,2,1);
            sgtitle(fname)
                plot(app.x_axis, app.depth_corrected)
                xlim("tight")
                if max(app.depth_corrected) > 1
                    ylim([0 max(app.depth_corrected)])
                else 
                    ylim([0 1])
                end
                title("Fluorescence, depth corrected")
                xlabel("Distance (cm)"),
                ylabel("Fluorescence (AU)")
            subplot(2,2,3);
                scatter(app.depth, app.FL_data,".")
                title("Fluorescence, experimental vs model")
                hold on
                scatter(app.depth, app.FL_processed,".")
                hold off
                legend("Exp", "Model")
                xlabel("Depth (cm)"),
                ylabel("Fluorescence (AU)")
                axis tight
            subplot(2,2,2);
                imagesc(app.FL_image)
                title("Colormap")
                colorbar
                axis tight
            subplot(2,2,4)
                plot(app.x_axis, app.depth)
                set ( gca, 'ydir', 'reverse' )
                title("Detected surface")
                xlabel("Distance (cm)"),
                ylabel("Depth (cm)")
                axis equal
                axis tight
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ThresholdSliderLabel = uilabel(app.UIFigure);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.UIFigure);
            app.ThresholdSlider.Limits = [0 150];
            app.ThresholdSlider.MajorTicks = [0 25 50 75 100 125 150];
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderValueChanging, true);
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.SeeDetailsButton = uibutton(app.UIFigure, 'push');
            app.SeeDetailsButton.ButtonPushedFcn = createCallbackFcn(app, @SeeDetailsButtonPushed, true);
            app.SeeDetailsButton.Text = 'See Details';
            app.ExportCurrentButton = uibutton(app.UIFigure, 'push');
            app.ExportCurrentButton.Text = 'Export Current';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.Text = 'Update';
            app.SelectFilesButton = uibutton(app.UIFigure, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.ExportAllButton = uibutton(app.UIFigure, 'push');
            app.ExportAllButton.Text = 'Export All';
            app.VisibilitySliderLabel = uilabel(app.UIFigure);
            app.VisibilitySliderLabel.Text = 'Visibility';
            app.VisibilitySlider = uislider(app.UIFigure);
            app.VisibilitySlider.Limits = [0 150];
            app.VisibilitySlider.MajorTicks = [0 25 50 75 100 125 150];
            app.VisibilitySlider.ValueChangedFcn = createCallbackFcn(app, @VisibilitySliderValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end